classdef pieceAnalyzer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        VisorBieleUIFigure     matlab.ui.Figure
        Menu                   matlab.ui.container.Menu
        Toolbar                matlab.ui.container.Toolbar
        cursorTool             matlab.ui.container.toolbar.ToggleTool
        bBoxTool               matlab.ui.container.toolbar.ToggleTool
        deleteOutliersTool     matlab.ui.container.toolbar.ToggleTool
        appLayout              matlab.ui.container.GridLayout
        settingsLayout         matlab.ui.container.GridLayout
        resultsGroup           matlab.ui.container.TabGroup
        results                matlab.ui.container.Tab
        canvas                 matlab.ui.control.UIAxes
        configTabs             matlab.ui.container.TabGroup
        loadFiles              matlab.ui.container.Tab
        loadFilesLayout        matlab.ui.container.GridLayout
        Panel                  matlab.ui.container.Panel
        GridLayout             matlab.ui.container.GridLayout
        calculateErrorButton   matlab.ui.control.Button
        startProcessing        matlab.ui.control.Button
        loadImageButton        matlab.ui.control.Button
        previewImage           matlab.ui.control.Image
        loadSVGButton          matlab.ui.control.Button
        previewSVG             matlab.ui.control.Image
        confParametersTab      matlab.ui.container.Tab
        ParamsLayout           matlab.ui.container.GridLayout
        subpixelPanel          matlab.ui.container.Panel
        subpixelLayout         matlab.ui.container.GridLayout
        threshold              matlab.ui.control.NumericEditField
        smoothIters            matlab.ui.control.NumericEditField
        IteracionesdesuavizadoEditFieldLabel  matlab.ui.control.Label
        UmbralEditFieldLabel   matlab.ui.control.Label
        DBSCANPanel            matlab.ui.container.Panel
        DBSCANLayout           matlab.ui.container.GridLayout
        epsilon                matlab.ui.control.NumericEditField
        minPoints              matlab.ui.control.EditField
        MnimodepuntosLabel     matlab.ui.control.Label
        EpsilonEditFieldLabel  matlab.ui.control.Label
        errorPanel             matlab.ui.container.Panel
        errorLayout            matlab.ui.container.GridLayout
        ToleranciaLabel        matlab.ui.control.Label
        pixelTomm              matlab.ui.control.NumericEditField
        tolerance              matlab.ui.control.NumericEditField
        pxammLabel             matlab.ui.control.Label
    end

 
    properties (Access = private)
        stateApp;
        feedbackManager;

        canvasWrapper;
        previewImageWrapper;
        previewSVGWrapper;
        resultsConsoleWrapper;
        
        toolsController;
        imageController;
        svgController;
        filterPipeController;
        errorPipeController;

        modelImage;
        modelSVG;
        configParams struct;
    end
    
    methods (Access = private)
        
        function colectParams(app)
            app.configParams.subpixel.threshold = app.threshold.Value;
            app.configParams.subpixel.smoothIters = app.smoothIters.Value;

            app.configParams.DBSCAN.epsilon = app.epsilon.Value;
            app.configParams.DBSCAN.minPoints = app.minPoints.Value;

            app.configParams.error.pixelTomm = app.pixelTomm.Value;
            app.configParams.error.tolerance = app.tolerance.Value;
        end

        
        function resetImageContext(app)
        % resetImageContext() Completely resets all state, models, and UI elements 
        % related to the current image. 
        
            % --- Clean BBoxes ---
            if ~isempty(app.modelImage)
                app.modelImage.clearBBoxes();
            end
        
            % --- Clean results console ---
            if ~isempty(app.resultsConsoleWrapper)
                app.resultsConsoleWrapper.clearTabs();
            end
        
            % --- Clean canvas ---
            if ~isempty(app.canvas)
                app.canvasWrapper.cleanCanvas();
            end
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % State App
            app.stateApp = models.AppState(app.cursorTool);
            app.feedbackManager = viewWrapper.FeedbackManager(app.VisorBieleUIFigure);
            
            % Wrapper components
            app.canvasWrapper = viewWrapper.Canvas(app.canvas);
            app.previewImageWrapper = viewWrapper.PreviewFile(app.previewImage);
            app.previewSVGWrapper = viewWrapper.PreviewFile(app.previewSVG);
            app.resultsConsoleWrapper = viewWrapper.results.Console(app.resultsGroup);

            % Create models
            app.modelImage = models.Image();
            app.modelSVG   = models.SVG();

            % Create controllers
            app.toolsController = controllers.ToolsController(app.stateApp, ...
                app.modelImage, ...
                app.canvasWrapper, ...
                app.cursorTool, ...
                app.bBoxTool, ...
                app.deleteOutliersTool, ...
                app.feedbackManager);
            app.imageController = controllers.ImageController(app.stateApp, ...
                app.modelImage, ...
                app.previewImageWrapper, ...
                app.canvasWrapper, ...
                app.resultsConsoleWrapper, ...
                app.feedbackManager);
            app.svgController = controllers.SVGController(app.stateApp, ...
                app.modelSVG, ...
                app.previewSVGWrapper, ...
                app.canvasWrapper, ...
                app.feedbackManager);
            app.filterPipeController = controllers.FilterPipeController(app.stateApp, ...
                app.modelImage, ...
                app.canvasWrapper, ...
                app.resultsConsoleWrapper, ...
                app.feedbackManager);
            app.errorPipeController = controllers.ErrorPipeController(app.stateApp, ...
                app.modelImage, ...
                app.modelSVG, ...
                app.canvasWrapper, ...
                app.resultsConsoleWrapper, ...
                app.feedbackManager);
            
            % Pipe params
            app.configParams = struct( ...
                'subpixel', struct( ...
                    'threshold', app.threshold.Value, ...
                    'smoothIters', app.smoothIters.Value), ...
                'DBSCAN', struct( ...
                    'epsilon', app.epsilon.Value, ...
                    'minPoints', app.minPoints.Value), ...
                'error', struct( ...
                    'pixelTomm', app.pixelTomm.Value, ...
                    'tolerance', app.tolerance.Value) ...
            );
        end

        % Button pushed function: loadImageButton
        function loadImageButtonPushed(app, event)
            app.resetImageContext();

            % Allowed files
            [file, path] = uigetfile({'*.png;*.jpg;*.jpeg;', ...
                'Imágenes (*.png, *.jpg, *.jpeg)'}, ...
                'Selecciona una imagen');

            % Not selected file
            if isequal(file, 0)
                return;
            end
            
            app.imageController.loadImageFromDialog(path, file);
        end

        % Image clicked function: previewImage
        function previewImageClicked(app, event)
            app.imageController.previewImageOnCanva();
        end

        % Button pushed function: loadSVGButton
        function loadSVGButtonPushed(app, event)
            % Allowed files
            [file, path] = uigetfile({'*.svg', 'Archivos SVG (*.svg)'}, ...
                                                 'Selecciona un archivo SVG');
            % Not selected file
            if isequal(file, 0)
                return;
            end
            
            app.svgController.loadSVGFromDialog(path, file);
        end

        % Image clicked function: previewSVG
        function previewSVGClicked(app, event)
            app.svgController.previewSVGOnCanva();
        end

        % On callback: cursorTool
        function cursorToolOn(app, event)
            app.stateApp.setActiveTool(app.cursorTool);
            controllers.ToolsController.resetEvents(app.canvas);
        end

        % Off callback: cursorTool
        function cursorToolOff(app, event)
            if app.stateApp.getActiveTool() == app.cursorTool
                app.stateApp.forceReactivateTool();
            end
            controllers.ToolsController.resetEvents(app.canvas);
        end

        % On callback: bBoxTool
        function bBoxToolOn(app, event)
            app.stateApp.setActiveTool(app.bBoxTool);
            app.toolsController.bboxEvents(app.canvas);
        end

        % Off callback: bBoxTool
        function bBoxToolOff(app, event)
            app.stateApp.setActiveTool(app.cursorTool);
            controllers.ToolsController.resetEvents(app.canvas);
        end

        % On callback: deleteOutliersTool
        function deleteOutliersToolOn(app, event)
            app.stateApp.setActiveTool(app.deleteOutliersTool);
            app.toolsController.deleteOutliersEvents(app.canvas);
        end

        % Off callback: deleteOutliersTool
        function deleteOutliersToolOff(app, event)
            app.stateApp.setActiveTool(app.cursorTool);
            controllers.ToolsController.resetEvents(app.canvas);
        end

        % Button pushed function: startProcessing
        function startProcessingButtonPushed(app, event)
            app.colectParams();
            app.filterPipeController.filterPipeline(app.configParams);
        end

        % Button pushed function: calculateErrorButton
        function calculateErrorButtonPushed(app, event)
            app.colectParams();
            app.errorPipeController.errorPipeline(app.configParams);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.VisorBieleUIFigure = uifigure('Visible', 'off');
            colormap(app.VisorBieleUIFigure, 'white');
            app.VisorBieleUIFigure.Name = 'Visor Biele';
            app.VisorBieleUIFigure.Icon = fullfile(pathToMLAPP, 'picturesApp', 'logoBiele.png');
            app.Menu = uimenu(app.VisorBieleUIFigure);
            app.Menu.Text = 'Menu';
            app.Toolbar = uitoolbar(app.VisorBieleUIFigure);
            app.cursorTool = uitoggletool(app.Toolbar);
            app.cursorTool.Tooltip = {'Cursor'};
            app.cursorTool.Icon = fullfile(pathToMLAPP, 'picturesApp', 'cursor-240.png');
            app.cursorTool.OffCallback = createCallbackFcn(app, @cursorToolOff, true);
            app.cursorTool.OnCallback = createCallbackFcn(app, @cursorToolOn, true);
            app.bBoxTool = uitoggletool(app.Toolbar);
            app.bBoxTool.Tooltip = {'Dibujar BoundingBox sobre la pieza'};
            app.bBoxTool.Icon = fullfile(pathToMLAPP, 'picturesApp', 'bBoxToolIcon.png');
            app.bBoxTool.OffCallback = createCallbackFcn(app, @bBoxToolOff, true);
            app.bBoxTool.OnCallback = createCallbackFcn(app, @bBoxToolOn, true);
            app.deleteOutliersTool = uitoggletool(app.Toolbar);
            app.deleteOutliersTool.OffCallback = createCallbackFcn(app, @deleteOutliersToolOff, true);
            app.deleteOutliersTool.OnCallback = createCallbackFcn(app, @deleteOutliersToolOn, true);
            app.appLayout = uigridlayout(app.VisorBieleUIFigure);
            app.configTabs = uitabgroup(app.appLayout);
            app.loadFiles = uitab(app.configTabs);
            app.loadFiles.Title = 'Carga de archivos';
            app.loadFilesLayout = uigridlayout(app.loadFiles);
            app.previewSVG = uiimage(app.loadFilesLayout);
            app.previewSVG.ImageClickedFcn = createCallbackFcn(app, @previewSVGClicked, true);
            app.loadSVGButton = uibutton(app.loadFilesLayout, 'push');
            app.loadSVGButton.ButtonPushedFcn = createCallbackFcn(app, @loadSVGButtonPushed, true);
            app.loadSVGButton.Text = 'Cargar SVG';
            app.previewImage = uiimage(app.loadFilesLayout);
            app.previewImage.ImageClickedFcn = createCallbackFcn(app, @previewImageClicked, true);
            app.loadImageButton = uibutton(app.loadFilesLayout, 'push');
            app.loadImageButton.ButtonPushedFcn = createCallbackFcn(app, @loadImageButtonPushed, true);
            app.loadImageButton.Text = 'Cargar imagen';
            app.Panel = uipanel(app.loadFilesLayout);
            app.Panel.Title = 'Panel';
            app.GridLayout = uigridlayout(app.Panel);
            app.startProcessing = uibutton(app.GridLayout, 'push');
            app.startProcessing.ButtonPushedFcn = createCallbackFcn(app, @startProcessingButtonPushed, true);
            app.startProcessing.Text = 'Iniciar procesamiento';
            app.calculateErrorButton = uibutton(app.GridLayout, 'push');
            app.calculateErrorButton.ButtonPushedFcn = createCallbackFcn(app, @calculateErrorButtonPushed, true);
            app.calculateErrorButton.Text = 'Calcular error';
            app.confParametersTab = uitab(app.configTabs);
            app.confParametersTab.Title = 'Parámetros de configuración';
            app.ParamsLayout = uigridlayout(app.confParametersTab);
            app.errorPanel = uipanel(app.ParamsLayout);
            app.errorPanel.Title = 'Error';
            app.errorLayout = uigridlayout(app.errorPanel);
            app.pxammLabel = uilabel(app.errorLayout);
            app.pxammLabel.Text = 'px a mm';
            app.tolerance = uieditfield(app.errorLayout, 'numeric');
            app.tolerance.Placeholder = '0.3';
            app.tolerance.Value = 0.3;
            app.pixelTomm = uieditfield(app.errorLayout, 'numeric');
            app.pixelTomm.Placeholder = '15';
            app.pixelTomm.Value = 15;
            app.ToleranciaLabel = uilabel(app.errorLayout);
            app.ToleranciaLabel.Text = 'Tolerancia';
            app.DBSCANPanel = uipanel(app.ParamsLayout);
            app.DBSCANPanel.Title = 'DBSCAN';
            app.DBSCANLayout = uigridlayout(app.DBSCANPanel);
            app.EpsilonEditFieldLabel = uilabel(app.DBSCANLayout);
            app.EpsilonEditFieldLabel.Text = 'Epsilon';
            app.MnimodepuntosLabel = uilabel(app.DBSCANLayout);
            app.MnimodepuntosLabel.Text = 'Mínimo de puntos';
            app.minPoints = uieditfield(app.DBSCANLayout, 'text');
            app.minPoints.InputType = 'digits';
            app.minPoints.Placeholder = '4';
            app.minPoints.Value = '4';
            app.epsilon = uieditfield(app.DBSCANLayout, 'numeric');
            app.epsilon.Placeholder = '6';
            app.epsilon.Value = 6;
            app.subpixelPanel = uipanel(app.ParamsLayout);
            app.subpixelPanel.Title = 'Subpixel';
            app.subpixelLayout = uigridlayout(app.subpixelPanel);
            app.UmbralEditFieldLabel = uilabel(app.subpixelLayout);
            app.UmbralEditFieldLabel.Text = 'Umbral';
            app.IteracionesdesuavizadoEditFieldLabel = uilabel(app.subpixelLayout);
            app.IteracionesdesuavizadoEditFieldLabel.Text = 'Iteraciones de suavizado';
            app.smoothIters = uieditfield(app.subpixelLayout, 'numeric');
            app.smoothIters.Placeholder = '1';
            app.smoothIters.Value = 1;
            app.threshold = uieditfield(app.subpixelLayout, 'numeric');
            app.threshold.Placeholder = '20';
            app.threshold.Value = 5;
            app.settingsLayout = uigridlayout(app.appLayout);
            app.canvas = uiaxes(app.settingsLayout);
            xlabel(app.canvas, 'X')
            ylabel(app.canvas, 'Y')
            zlabel(app.canvas, 'Z')
            colormap(app.canvas, 'gray')
            app.resultsGroup = uitabgroup(app.settingsLayout);
            app.results = uitab(app.resultsGroup);
            app.results.Title = 'Resultados';
            % Show the figure after all components are created
            app.VisorBieleUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = pieceAnalyzer

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.VisorBieleUIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.VisorBieleUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.VisorBieleUIFigure)
        end
    end
end